classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        FRplot               matlab.ui.control.UIAxes
        Fplot                matlab.ui.control.UIAxes
        dFplot               matlab.ui.control.UIAxes
        lplot                matlab.ui.control.UIAxes
        vplot                matlab.ui.control.UIAxes
        aplot                matlab.ui.control.UIAxes
        FgainSliderLabel     matlab.ui.control.Label
        FgainSlider          matlab.ui.control.Slider
        dFgainSliderLabel    matlab.ui.control.Label
        dFgainSlider         matlab.ui.control.Slider
        FoffsetSliderLabel   matlab.ui.control.Label
        FoffsetSlider        matlab.ui.control.Slider
        dFoffsetSliderLabel  matlab.ui.control.Label
        dFoffsetSlider       matlab.ui.control.Slider
        Slider_5Label        matlab.ui.control.Label
        Slider_5             matlab.ui.control.Slider
        Slider_6Label        matlab.ui.control.Label
        Slider_6             matlab.ui.control.Slider
        Slider_7Label        matlab.ui.control.Label
        Slider_7             matlab.ui.control.Slider
        Slider_8Label        matlab.ui.control.Label
        Slider_8             matlab.ui.control.Slider
        Slider_9Label        matlab.ui.control.Label
        Slider_9             matlab.ui.control.Slider
        Slider_10Label       matlab.ui.control.Label
        Slider_10            matlab.ui.control.Slider
        LoadButton           matlab.ui.control.Button
        ModelDropDownLabel   matlab.ui.control.Label
        ModelDropDown        matlab.ui.control.DropDown
        DelaySliderLabel     matlab.ui.control.Label
        DelaySlider          matlab.ui.control.Slider
    end

    
    properties (Access = private)
        ExpData % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile;
            load([path file]);
            app.ExpData = data;
        end

        % Value changed function: DelaySlider, FgainSlider, 
        % FoffsetSlider, dFgainSlider, dFoffsetSlider
        function FgainSliderValueChanged(app, event)
            perts = 1;
            ISI = diff(app.ExpData(perts).spiketimes); % 'ISI'
            firing_rate = [0; 1./ISI];   % 'firing_rate'
            
            Force = app.ExpData(perts).Force;
            dFdt = [0; diff(app.ExpData(perts).Force)];
            Length = app.ExpData(perts).Length;
            Velocity = app.ExpData(perts).Velocity;
            dVdt = [0; diff(app.ExpData(perts).Velocity)];
            delay = round(app.DelaySlider.Value);
            
            data2.Force = Force(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*2000)+delay);
            data2.dFdt = dFdt(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*2000)+delay);
            data2.Length = Length(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*2000)+delay);
            data2.Velocity = Velocity(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*2000)+delay);
            data2.dVdt = dVdt(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*2000)+delay);
            
            hold(app.FRplot, 'off')
            plot(app.FRplot,app.ExpData(perts).spiketimes(2:end),firing_rate(2:end),'o')
            hold(app.FRplot, 'on')
            
            if str2double(app.ModelDropDown.Value) == 1
                gains = [app.FgainSlider.Value; app.dFgainSlider.Value; app.FoffsetSlider.Value; app.dFoffsetSlider.Value;];
                
                fit = kinetics(data2,gains,1);
                
                
                plot(app.FRplot,app.ExpData(perts).spiketimes(2:end),fit(2:end))
            end
            
            plot(app.Fplot,app.ExpData(perts).spiketimes(2:end),data2.Force(2:end))   
            plot(app.dFplot, app.ExpData(perts).spiketimes(2:end),data2.dFdt(2:end))
            plot(app.lplot,app.ExpData(perts).spiketimes(2:end),data2.Length(2:end))   
            plot(app.vplot, app.ExpData(perts).spiketimes(2:end),data2.Velocity(2:end))
            plot(app.aplot,app.ExpData(perts).spiketimes(2:end),data2.dVdt(2:end))   

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FRplot = uiaxes(app.UIFigure);
            title(app.FRplot, 'Title', 'Interpreter', 'none')
            xlabel(app.FRplot, 'X', 'Interpreter', 'none')
            ylabel(app.FRplot, 'Y', 'Interpreter', 'none')
            app.Fplot = uiaxes(app.UIFigure);
            title(app.Fplot, 'Title', 'Interpreter', 'none')
            xlabel(app.Fplot, 'X', 'Interpreter', 'none')
            ylabel(app.Fplot, 'Y', 'Interpreter', 'none')
            app.dFplot = uiaxes(app.UIFigure);
            title(app.dFplot, 'Title', 'Interpreter', 'none')
            xlabel(app.dFplot, 'X', 'Interpreter', 'none')
            ylabel(app.dFplot, 'Y', 'Interpreter', 'none')
            app.lplot = uiaxes(app.UIFigure);
            title(app.lplot, 'Title', 'Interpreter', 'none')
            xlabel(app.lplot, 'X', 'Interpreter', 'none')
            ylabel(app.lplot, 'Y', 'Interpreter', 'none')
            app.vplot = uiaxes(app.UIFigure);
            title(app.vplot, 'Title', 'Interpreter', 'none')
            xlabel(app.vplot, 'X', 'Interpreter', 'none')
            ylabel(app.vplot, 'Y', 'Interpreter', 'none')
            app.aplot = uiaxes(app.UIFigure);
            title(app.aplot, 'Title', 'Interpreter', 'none')
            xlabel(app.aplot, 'X', 'Interpreter', 'none')
            ylabel(app.aplot, 'Y', 'Interpreter', 'none')
            app.FgainSliderLabel = uilabel(app.UIFigure);
            app.FgainSliderLabel.Text = 'Fgain';
            app.FgainSlider = uislider(app.UIFigure);
            app.FgainSlider.Limits = [0 10];
            app.FgainSlider.ValueChangedFcn = createCallbackFcn(app, @FgainSliderValueChanged, true);
            app.dFgainSliderLabel = uilabel(app.UIFigure);
            app.dFgainSliderLabel.Text = 'dFgain';
            app.dFgainSlider = uislider(app.UIFigure);
            app.dFgainSlider.Limits = [0 1000];
            app.dFgainSlider.ValueChangedFcn = createCallbackFcn(app, @FgainSliderValueChanged, true);
            app.FoffsetSliderLabel = uilabel(app.UIFigure);
            app.FoffsetSliderLabel.Text = 'Foffset';
            app.FoffsetSlider = uislider(app.UIFigure);
            app.FoffsetSlider.Limits = [-500 500];
            app.FoffsetSlider.ValueChangedFcn = createCallbackFcn(app, @FgainSliderValueChanged, true);
            app.dFoffsetSliderLabel = uilabel(app.UIFigure);
            app.dFoffsetSliderLabel.Text = 'dFoffset';
            app.dFoffsetSlider = uislider(app.UIFigure);
            app.dFoffsetSlider.Limits = [-500 500];
            app.dFoffsetSlider.ValueChangedFcn = createCallbackFcn(app, @FgainSliderValueChanged, true);
            app.Slider_5Label = uilabel(app.UIFigure);
            app.Slider_5Label.Text = 'Slider';
            app.Slider_5 = uislider(app.UIFigure);
            app.Slider_6Label = uilabel(app.UIFigure);
            app.Slider_6Label.Text = 'Slider';
            app.Slider_6 = uislider(app.UIFigure);
            app.Slider_7Label = uilabel(app.UIFigure);
            app.Slider_7Label.Text = 'Slider';
            app.Slider_7 = uislider(app.UIFigure);
            app.Slider_8Label = uilabel(app.UIFigure);
            app.Slider_8Label.Text = 'Slider';
            app.Slider_8 = uislider(app.UIFigure);
            app.Slider_9Label = uilabel(app.UIFigure);
            app.Slider_9Label.Text = 'Slider';
            app.Slider_9 = uislider(app.UIFigure);
            app.Slider_10Label = uilabel(app.UIFigure);
            app.Slider_10Label.Text = 'Slider';
            app.Slider_10 = uislider(app.UIFigure);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.ModelDropDown.Value = '1';
            app.DelaySliderLabel = uilabel(app.UIFigure);
            app.DelaySliderLabel.Text = 'Delay';
            app.DelaySlider = uislider(app.UIFigure);
            app.DelaySlider.Limits = [1 99];
            app.DelaySlider.ValueChangedFcn = createCallbackFcn(app, @FgainSliderValueChanged, true);
            app.DelaySlider.Value = 55;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end