classdef app1_SNS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LoadButton                    matlab.ui.control.Button
        ModelDropDownLabel            matlab.ui.control.Label
        ModelDropDown                 matlab.ui.control.DropDown
        DelaySliderLabel              matlab.ui.control.Label
        DelaySlider                   matlab.ui.control.Slider
        fGainKnobLabel                matlab.ui.control.Label
        fGainKnob                     matlab.ui.control.Knob
        dfGainKnobLabel               matlab.ui.control.Label
        dfGainKnob                    matlab.ui.control.Knob
        lGainKnobLabel                matlab.ui.control.Label
        lGainKnob                     matlab.ui.control.Knob
        vGainKnobLabel                matlab.ui.control.Label
        vGainKnob                     matlab.ui.control.Knob
        aGainKnobLabel                matlab.ui.control.Label
        aGainKnob                     matlab.ui.control.Knob
        fascicle_lGainlowcomplianceKnobLabel  matlab.ui.control.Label
        fascicle_lGainlowcomplianceKnob  matlab.ui.control.Knob
        fascicle_vGainlowcomplianceKnobLabel  matlab.ui.control.Label
        fascicle_vGainlowcomplianceKnob  matlab.ui.control.Knob
        fascicle_aGainlowcomplianceKnobLabel  matlab.ui.control.Label
        fascicle_aGainlowcomplianceKnob  matlab.ui.control.Knob
        fascicle_lGainhighcomplianceKnobLabel  matlab.ui.control.Label
        fascicle_lGainhighcomplianceKnob  matlab.ui.control.Knob
        fascicle_vGainhighcomplianceKnobLabel  matlab.ui.control.Label
        fascicle_vGainhighcomplianceKnob  matlab.ui.control.Knob
        fascicle_aGainhighcomplianceKnobLabel  matlab.ui.control.Label
        fascicle_aGainhighcomplianceKnob  matlab.ui.control.Knob
        fOffsetKnobLabel              matlab.ui.control.Label
        fOffsetKnob                   matlab.ui.control.Knob
        dfOffsetKnobLabel             matlab.ui.control.Label
        dfOffsetKnob                  matlab.ui.control.Knob
        lOffsetKnob_2Label            matlab.ui.control.Label
        lOffsetKnob_2                 matlab.ui.control.Knob
        vOffsetExponentProchazkaKnobLabel  matlab.ui.control.Label
        vOffsetExponentProchazkaKnob  matlab.ui.control.Knob
        aOffsetKnob_2Label            matlab.ui.control.Label
        aOffsetKnob_2                 matlab.ui.control.Knob
        fascicle_lOffsetlowcomplianceKnob_2Label  matlab.ui.control.Label
        fascicle_lOffsetlowcomplianceKnob_2  matlab.ui.control.Knob
        fascicle_vOffsetlowcomplianceKnobLabel  matlab.ui.control.Label
        fascicle_vOffsetlowcomplianceKnob  matlab.ui.control.Knob
        fascicle_aOffsetlowcomplianceKnobLabel  matlab.ui.control.Label
        fascicle_aOffsetlowcomplianceKnob  matlab.ui.control.Knob
        fascicle_lOffsethighcomplianceKnob_2Label  matlab.ui.control.Label
        fascicle_lOffsethighcomplianceKnob_2  matlab.ui.control.Knob
        fascicle_vOffsethighcomplianceKnobLabel  matlab.ui.control.Label
        fascicle_vOffsethighcomplianceKnob  matlab.ui.control.Knob
        fascicle_aOffsethighcomplianceKnobLabel  matlab.ui.control.Label
        fascicle_aOffsethighcomplianceKnob  matlab.ui.control.Knob
        FRplot                        matlab.ui.control.UIAxes
        Fplot                         matlab.ui.control.UIAxes
        dFplot                        matlab.ui.control.UIAxes
        lplot                         matlab.ui.control.UIAxes
        vplot                         matlab.ui.control.UIAxes
        aplot                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ExpData % Description
        flags
    end
    
    methods (Access = private)
        
        function plotFigs(app)
            perts = 1;
            fs = 2000;
            ISI = diff(app.ExpData(perts).spiketimes); % 'ISI'
            firing_rate = [0; 1./ISI];   % 'firing_rate'
            
            Time = app.ExpData(perts).time;
            Force = app.ExpData(perts).Force;
            dFdt = [0; diff(app.ExpData(perts).Force)];
            Length = app.ExpData(perts).Length;
            Velocity = app.ExpData(perts).Velocity;
            dVdt = [0; diff(app.ExpData(perts).Velocity)];
            delay = round(app.DelaySlider.Value);
            
            if delay>length(app.ExpData), delay=length(app.ExpData); end
            
            data2.Force = Force(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
            data2.dFdt = dFdt(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
            data2.Length = Length(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
            data2.Velocity = Velocity(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
            data2.dVdt = dVdt(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
            
            hold(app.FRplot, 'off')
            plot(app.FRplot,app.ExpData(perts).spiketimes(2:end),firing_rate(2:end),'o')
            hold(app.FRplot, 'on')
            
            if isequal((app.ModelDropDown.Value),'Kinetics')
                gains = [app.fGainKnob.Value; app.dfGainKnob.Value; app.fOffsetKnob.Value; app.dfOffsetKnob.Value];
                fit = kinetics(data2,gains,1);
                
            elseif isequal((app.ModelDropDown.Value),'Kinematics')
                gains = [app.lGainKnob.Value; app.vGainKnob.Value; app.aGainKnob.Value; app.lOffsetKnob_2.Value;...
                    app.vOffsetExponentProchazkaKnob.Value; app.aOffsetKnob_2.Value];
                app.flags.model = 2;
                fit = kinematics(data2,gains,app.flags);
                
            elseif isequal((app.ModelDropDown.Value),'Prochazka')
                gains = [app.vGainKnob.Value; app.vOffsetExponentProchazkaKnob.Value; app.lGainKnob.Value; app.lOffsetKnob_2.Value];
                fit = prochazka(data2,gains);
                
            elseif isequal(app.ModelDropDown.Value,'FibreKinematics-LowCompliance')
                [LengthF_lc,filt_VF_lc,filt_dVFdt_lc] = fasKinematicsLC(app,Force,Length,Time);
                
                gains = [app.fascicle_lGainlowcomplianceKnob.Value; app.fascicle_aGainlowcomplianceKnob.Value; app.fascicle_aGainlowcomplianceKnob.Value;...
                    app.fascicle_lOffsetlowcomplianceKnob_2.Value; app.fascicle_vOffsetlowcomplianceKnob.Value; app.fascicle_aOffsetlowcomplianceKnob.Value];
                app.flags.model = 4;
                data2.LengthF=LengthF_lc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                data2.VelocityF=filt_VF_lc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                data2.dVFdt=filt_dVFdt_lc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                
                fit = kinematics(data2,gains,app.flags);
                
            elseif isequal(app.ModelDropDown.Value,'FibreKinematics-HighCompliance')
                [LengthF_hc,filt_VF_hc,filt_dVFdt_hc] = fasKinematicsHC(app,Force,Length,Time);
                
                gains = [app.fascicle_lGainhighcomplianceKnob.Value; app.fascicle_vGainhighcomplianceKnob.Value; app.fascicle_aGainhighcomplianceKnob.Value;...
                    app.fascicle_lOffsethighcomplianceKnob_2.Value; app.fascicle_vOffsethighcomplianceKnob.Value; app.fascicle_aOffsethighcomplianceKnob.Value];
                app.flags.model = 5;
                data2.LengthF_hc=LengthF_hc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                data2.VelocityF_hc=filt_VF_hc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                data2.dVFdt_hc=filt_dVFdt_hc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                
                fit = kinematics(data2,gains,app.flags);
                
            elseif isequal((app.ModelDropDown.Value),'MixedKin')
                [LengthF_lc,filt_VF_lc,filt_dVFdt_lc] = fasKinematicsLC(app,Force,Length,Time);
                [LengthF_hc,filt_VF_hc,filt_dVFdt_hc] = fasKinematicsHC(app,Force,Length,Time);
                
                gains = [app.fGainKnob.Value; app.dfGainKnob.Value; app.lGainKnob.Value; app.vGainKnob.Value; app.aGainKnob.Value;...
                    app.fascicle_lGainlowcomplianceKnob.Value; app.fascicle_vGainlowcomplianceKnob.Value; app.fascicle_aGainlowcomplianceKnob.Value;...
                    app.fascicle_lGainhighcomplianceKnob.Value; app.fascicle_vGainhighcomplianceKnob.Value; app.fascicle_aGainhighcomplianceKnob.Value; app.vGainKnob.Value;...
                    app.fOffsetKnob.Value; app.dfOffsetKnob.Value; app.lOffsetKnob_2.Value; app.vOffsetExponentProchazkaKnob.Value; app.aOffsetKnob_2.Value;...
                    app.fascicle_lOffsetlowcomplianceKnob_2.Value; app.fascicle_vOffsetlowcomplianceKnob.Value; app.fascicle_aOffsetlowcomplianceKnob.Value;...
                    app.fascicle_lOffsethighcomplianceKnob_2.Value; app.fascicle_vOffsethighcomplianceKnob.Value; app.fascicle_aOffsethighcomplianceKnob.Value;...
                    app.lOffsetKnob_2.Value; app.vOffsetExponentProchazkaKnob.Value];
                data2.LengthF=LengthF_lc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                data2.VelocityF=filt_VF_lc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                data2.dVFdt=filt_dVFdt_lc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                data2.LengthF_hc=LengthF_hc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                data2.VelocityF_hc=filt_VF_hc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                data2.dVFdt_hc=filt_dVFdt_hc(round((app.ExpData(perts).spiketimes-app.ExpData(perts).spiketimes(1))*fs)+delay);
                fit = mixedKin(data2,gains);
                
            end
            plot(app.FRplot,app.ExpData(perts).spiketimes(2:end),fit(2:end))
            plot(app.Fplot,app.ExpData(perts).spiketimes(2:end),data2.Force(2:end))
            plot(app.dFplot,app.ExpData(perts).spiketimes(2:end),data2.dFdt(2:end))
            plot(app.lplot,app.ExpData(perts).spiketimes(2:end),data2.Length(2:end))
            plot(app.vplot,app.ExpData(perts).spiketimes(2:end),data2.Velocity(2:end))
            plot(app.aplot,app.ExpData(perts).spiketimes(2:end),data2.dVdt(2:end))
        end
        
        function [LengthF_lc,filt_VF_lc,filt_dVFdt_lc] = fasKinematicsLC(~,Force,Length,Time)
            fs=2000;
            a_lc = 2; % Exponential stiffness of tendon (lc = low compliance)
            Wnvel = 40/(0.5*fs);
            [Bvel,Avel] = butter(5,Wnvel,'low');
            Wnacc = 40/(0.5*fs);
            [B_dV,A_dV] = butter(6,Wnacc,'low');
            
            LengthT = 1/a_lc*log(a_lc*Force); % Estimate of tendon length assuming exponentially elastic tendon
            LengthT = LengthT - LengthT(1);
            LengthF_lc = Length - Length(1) - LengthT; % Estimate of change in fascicle length (L - L0) with exponentially elastic tendon
            
            raw_VF_lc = diff(LengthF_lc)./diff(Time);
            raw_VF_lc(end+1) = raw_VF_lc(end);
            filt_VF_lc = filtfilt(Bvel,Avel,raw_VF_lc);
            
            raw_dVFdt_lc = diff(filt_VF_lc)./diff(Time);
            raw_dVFdt_lc(end+1) = raw_dVFdt_lc(end);
            filt_dVFdt_lc = filtfilt(B_dV,A_dV,raw_dVFdt_lc);
        end
        
        function [LengthF_hc,filt_VF_hc,filt_dVFdt_hc] = fasKinematicsHC(~,Force,Length,Time)
            fs=2000;
            a_hc = 6; % Exponential stiffness of tendon (hc = high compliance)
            Wnvel = 40/(0.5*fs);
            [Bvel,Avel] = butter(5,Wnvel,'low');
            Wnacc = 40/(0.5*fs);
            [B_dV,A_dV] = butter(6,Wnacc,'low');
            
            LengthT_hc = 1/a_hc*log(a_hc*Force); % Estimate of tendon length assuming exponentially elastic tendon
            LengthT_hc = LengthT_hc - LengthT_hc(1);
            LengthF_hc = Length - Length(1) - LengthT_hc; % Estimate of change in fascicle length (L - L0) with exponentially elastic tendon
            
            raw_VF_hc = diff(LengthF_hc)./diff(Time);
            raw_VF_hc(end+1) = raw_VF_hc(end);
            filt_VF_hc = filtfilt(Bvel,Avel,raw_VF_hc);
            
            raw_dVFdt_hc = diff(filt_VF_hc)./diff(Time);
            raw_dVFdt_hc(end+1) = raw_dVFdt_hc(end);
            filt_dVFdt_hc = filtfilt(B_dV,A_dV,raw_dVFdt_hc);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile;
            load([path file]);
            app.ExpData = data;
        end

        % Value changed function: fascicle_aOffsethighcomplianceKnob
        function fascicle_aOffsethighcomplianceKnobValueChanged(app, event)
            %             value = app.fascicle_aOffsethighcomplianceKnob.Value;
            app.plotFigs();
        end

        % Value changed function: fascicle_vOffsethighcomplianceKnob
        function fascicle_vOffsethighcomplianceKnobValueChanged(app, event)
            %             value = app.fascicle_vOffsethighcomplianceKnob.Value;
            app.plotFigs();
        end

        % Value changed function: 
        % fascicle_lOffsethighcomplianceKnob_2
        function fascicle_lOffsethighcomplianceKnob_2ValueChanged(app, event)
            %             value = app.fascicle_lOffsethighcomplianceKnob_2.Value;
            app.plotFigs();
        end

        % Value changed function: fascicle_aOffsetlowcomplianceKnob
        function fascicle_aOffsetlowcomplianceKnobValueChanged(app, event)
            %             value = app.fascicle_aOffsetlowcomplianceKnob.Value;
            app.plotFigs();
        end

        % Value changed function: fascicle_vOffsetlowcomplianceKnob
        function fascicle_vOffsetlowcomplianceKnobValueChanged(app, event)
            %             value = app.fascicle_vOffsetlowcomplianceKnob.Value;
            app.plotFigs();
        end

        % Value changed function: 
        % fascicle_lOffsetlowcomplianceKnob_2
        function fascicle_lOffsetlowcomplianceKnob_2ValueChanged(app, event)
            %             value = app.fascicle_lOffsetlowcomplianceKnob_2.Value;
            app.plotFigs();
        end

        % Value changed function: aOffsetKnob_2
        function aOffsetKnob_2ValueChanged(app, event)
            %             value = app.aOffsetKnob_2.Value;
            app.plotFigs();
        end

        % Value changed function: vOffsetExponentProchazkaKnob
        function vOffsetExponentProchazkaKnobValueChanged(app, event)
            %             value = app.vOffsetExponentProchazkaKnob.Value;
            app.plotFigs();
        end

        % Value changed function: lOffsetKnob_2
        function lOffsetKnob_2ValueChanged(app, event)
            %             value = app.lOffsetKnob_2.Value;
            app.plotFigs();
        end

        % Value changed function: dfOffsetKnob
        function dfOffsetKnobValueChanged(app, event)
            %             value = app.dfOffsetKnob.Value;
            app.plotFigs();
        end

        % Value changed function: fOffsetKnob
        function fOffsetKnobValueChanged(app, event)
            %             value = app.fOffsetKnob.Value;
            app.plotFigs();
        end

        % Value changed function: fascicle_aGainhighcomplianceKnob
        function fascicle_aGainhighcomplianceKnobValueChanged(app, event)
            %             value = app.fascicle_aGainhighcomplianceKnob.Value;
            app.plotFigs();
        end

        % Value changed function: fascicle_vGainhighcomplianceKnob
        function fascicle_vGainhighcomplianceKnobValueChanged(app, event)
            %             value = app.fascicle_vGainhighcomplianceKnob.Value;
            app.plotFigs();
        end

        % Value changed function: fascicle_lGainhighcomplianceKnob
        function fascicle_lGainhighcomplianceKnobValueChanged(app, event)
            %             value = app.fascicle_lGainhighcomplianceKnob.Value;
            app.plotFigs();
        end

        % Value changed function: fascicle_aGainlowcomplianceKnob
        function fascicle_aGainlowcomplianceKnobValueChanged(app, event)
            %             value = app.fascicle_aGainlowcomplianceKnob.Value;
            app.plotFigs();
        end

        % Value changed function: fascicle_vGainlowcomplianceKnob
        function fascicle_vGainlowcomplianceKnobValueChanged(app, event)
            %             value = app.fascicle_vGainlowcomplianceKnob.Value;
            app.plotFigs();
        end

        % Value changed function: fascicle_lGainlowcomplianceKnob
        function fascicle_lGainlowcomplianceKnobValueChanged(app, event)
            %             value = app.fascicle_lGainlowcomplianceKnob.Value;
            app.plotFigs();
        end

        % Value changed function: aGainKnob
        function aGainKnobValueChanged(app, event)
            %             value = app.aGainKnob.Value;
            app.plotFigs();
        end

        % Value changed function: vGainKnob
        function vGainKnobValueChanged(app, event)
            %             value = app.vGainKnob.Value;
            app.plotFigs();
        end

        % Value changed function: lGainKnob
        function lGainKnobValueChanged(app, event)
            %             value = app.lGainKnob.Value;
            app.plotFigs();
        end

        % Value changed function: dfGainKnob
        function dfGainKnobValueChanged(app, event)
            %             value = app.dfGainKnob.Value;
            app.plotFigs();
        end

        % Value changed function: fGainKnob
        function fGainKnobValueChanged(app, event)
            %             value = app.fGainKnob.Value;
            app.plotFigs();
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            %             value = app.ModelDropDown.Value;
            app.plotFigs();
        end

        % Value changed function: DelaySlider
        function DelaySliderValueChanged(app, event)
            %             value = app.DelaySlider.Value;
            app.plotFigs();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'Kinetics', 'Kinematics', 'Prochazka', 'FibreKinematics-LowCompliance', 'FibreKinematics-HighCompliance', 'MixedKin'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'Kinetics';
            app.DelaySliderLabel = uilabel(app.UIFigure);
            app.DelaySliderLabel.Text = 'Delay';
            app.DelaySlider = uislider(app.UIFigure);
            app.DelaySlider.Limits = [1 99];
            app.DelaySlider.ValueChangedFcn = createCallbackFcn(app, @DelaySliderValueChanged, true);
            app.DelaySlider.Value = 55;
            app.fGainKnobLabel = uilabel(app.UIFigure);
            app.fGainKnobLabel.Text = 'fGain';
            app.fGainKnob = uiknob(app.UIFigure, 'continuous');
            app.fGainKnob.Limits = [0 20];
            app.fGainKnob.ValueChangedFcn = createCallbackFcn(app, @fGainKnobValueChanged, true);
            app.dfGainKnobLabel = uilabel(app.UIFigure);
            app.dfGainKnobLabel.Text = 'dfGain';
            app.dfGainKnob = uiknob(app.UIFigure, 'continuous');
            app.dfGainKnob.Limits = [0 200];
            app.dfGainKnob.ValueChangedFcn = createCallbackFcn(app, @dfGainKnobValueChanged, true);
            app.lGainKnobLabel = uilabel(app.UIFigure);
            app.lGainKnobLabel.Text = 'lGain';
            app.lGainKnob = uiknob(app.UIFigure, 'continuous');
            app.lGainKnob.Limits = [0 50];
            app.lGainKnob.ValueChangedFcn = createCallbackFcn(app, @lGainKnobValueChanged, true);
            app.vGainKnobLabel = uilabel(app.UIFigure);
            app.vGainKnobLabel.Text = 'vGain';
            app.vGainKnob = uiknob(app.UIFigure, 'continuous');
            app.vGainKnob.Limits = [0 50];
            app.vGainKnob.ValueChangedFcn = createCallbackFcn(app, @vGainKnobValueChanged, true);
            app.aGainKnobLabel = uilabel(app.UIFigure);
            app.aGainKnobLabel.Text = 'aGain';
            app.aGainKnob = uiknob(app.UIFigure, 'continuous');
            app.aGainKnob.Limits = [0 500];
            app.aGainKnob.ValueChangedFcn = createCallbackFcn(app, @aGainKnobValueChanged, true);
            app.fascicle_lGainlowcomplianceKnobLabel = uilabel(app.UIFigure);
            app.fascicle_lGainlowcomplianceKnobLabel.Text = {'fascicle_lGain'; 'low compliance'; ''};
            app.fascicle_lGainlowcomplianceKnob = uiknob(app.UIFigure, 'continuous');
            app.fascicle_lGainlowcomplianceKnob.Limits = [0 50];
            app.fascicle_lGainlowcomplianceKnob.ValueChangedFcn = createCallbackFcn(app, @fascicle_lGainlowcomplianceKnobValueChanged, true);
            app.fascicle_vGainlowcomplianceKnobLabel = uilabel(app.UIFigure);
            app.fascicle_vGainlowcomplianceKnobLabel.Text = {'fascicle_vGain'; 'low compliance'; ''};
            app.fascicle_vGainlowcomplianceKnob = uiknob(app.UIFigure, 'continuous');
            app.fascicle_vGainlowcomplianceKnob.Limits = [0 500];
            app.fascicle_vGainlowcomplianceKnob.ValueChangedFcn = createCallbackFcn(app, @fascicle_vGainlowcomplianceKnobValueChanged, true);
            app.fascicle_aGainlowcomplianceKnobLabel = uilabel(app.UIFigure);
            app.fascicle_aGainlowcomplianceKnobLabel.Text = {'fascicle_aGain'; 'low compliance'; ''};
            app.fascicle_aGainlowcomplianceKnob = uiknob(app.UIFigure, 'continuous');
            app.fascicle_aGainlowcomplianceKnob.Limits = [0 10];
            app.fascicle_aGainlowcomplianceKnob.ValueChangedFcn = createCallbackFcn(app, @fascicle_aGainlowcomplianceKnobValueChanged, true);
            app.fascicle_lGainhighcomplianceKnobLabel = uilabel(app.UIFigure);
            app.fascicle_lGainhighcomplianceKnobLabel.Text = {'fascicle_lGain'; 'high compliance'; ''};
            app.fascicle_lGainhighcomplianceKnob = uiknob(app.UIFigure, 'continuous');
            app.fascicle_lGainhighcomplianceKnob.Limits = [0 50];
            app.fascicle_lGainhighcomplianceKnob.ValueChangedFcn = createCallbackFcn(app, @fascicle_lGainhighcomplianceKnobValueChanged, true);
            app.fascicle_vGainhighcomplianceKnobLabel = uilabel(app.UIFigure);
            app.fascicle_vGainhighcomplianceKnobLabel.Text = {'fascicle_vGain'; 'high compliance'; ''};
            app.fascicle_vGainhighcomplianceKnob = uiknob(app.UIFigure, 'continuous');
            app.fascicle_vGainhighcomplianceKnob.Limits = [0 50];
            app.fascicle_vGainhighcomplianceKnob.ValueChangedFcn = createCallbackFcn(app, @fascicle_vGainhighcomplianceKnobValueChanged, true);
            app.fascicle_aGainhighcomplianceKnobLabel = uilabel(app.UIFigure);
            app.fascicle_aGainhighcomplianceKnobLabel.Text = {'fascicle_aGain'; 'high compliance'; ''};
            app.fascicle_aGainhighcomplianceKnob = uiknob(app.UIFigure, 'continuous');
            app.fascicle_aGainhighcomplianceKnob.Limits = [0 10];
            app.fascicle_aGainhighcomplianceKnob.ValueChangedFcn = createCallbackFcn(app, @fascicle_aGainhighcomplianceKnobValueChanged, true);
            app.fOffsetKnobLabel = uilabel(app.UIFigure);
            app.fOffsetKnobLabel.Text = 'fOffset';
            app.fOffsetKnob = uiknob(app.UIFigure, 'continuous');
            app.fOffsetKnob.Limits = [0 50];
            app.fOffsetKnob.ValueChangedFcn = createCallbackFcn(app, @fOffsetKnobValueChanged, true);
            app.dfOffsetKnobLabel = uilabel(app.UIFigure);
            app.dfOffsetKnobLabel.Text = 'dfOffset';
            app.dfOffsetKnob = uiknob(app.UIFigure, 'continuous');
            app.dfOffsetKnob.Limits = [0 500];
            app.dfOffsetKnob.ValueChangedFcn = createCallbackFcn(app, @dfOffsetKnobValueChanged, true);
            app.lOffsetKnob_2Label = uilabel(app.UIFigure);
            app.lOffsetKnob_2Label.Text = 'lOffset';
            app.lOffsetKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.lOffsetKnob_2.Limits = [0 50];
            app.lOffsetKnob_2.ValueChangedFcn = createCallbackFcn(app, @lOffsetKnob_2ValueChanged, true);
            app.vOffsetExponentProchazkaKnobLabel = uilabel(app.UIFigure);
            app.vOffsetExponentProchazkaKnobLabel.Text = {'vOffset/'; 'Exponent (Prochazka)'; ''};
            app.vOffsetExponentProchazkaKnob = uiknob(app.UIFigure, 'continuous');
            app.vOffsetExponentProchazkaKnob.Limits = [0 50];
            app.vOffsetExponentProchazkaKnob.ValueChangedFcn = createCallbackFcn(app, @vOffsetExponentProchazkaKnobValueChanged, true);
            app.aOffsetKnob_2Label = uilabel(app.UIFigure);
            app.aOffsetKnob_2Label.Text = 'aOffset';
            app.aOffsetKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.aOffsetKnob_2.Limits = [0 500];
            app.aOffsetKnob_2.ValueChangedFcn = createCallbackFcn(app, @aOffsetKnob_2ValueChanged, true);
            app.fascicle_lOffsetlowcomplianceKnob_2Label = uilabel(app.UIFigure);
            app.fascicle_lOffsetlowcomplianceKnob_2Label.Text = {'fascicle_lOffset'; 'low compliance'; ''};
            app.fascicle_lOffsetlowcomplianceKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.fascicle_lOffsetlowcomplianceKnob_2.Limits = [0 500];
            app.fascicle_lOffsetlowcomplianceKnob_2.ValueChangedFcn = createCallbackFcn(app, @fascicle_lOffsetlowcomplianceKnob_2ValueChanged, true);
            app.fascicle_vOffsetlowcomplianceKnobLabel = uilabel(app.UIFigure);
            app.fascicle_vOffsetlowcomplianceKnobLabel.Text = {'fascicle_vOffset'; 'low compliance'; ''};
            app.fascicle_vOffsetlowcomplianceKnob = uiknob(app.UIFigure, 'continuous');
            app.fascicle_vOffsetlowcomplianceKnob.Limits = [0 500];
            app.fascicle_vOffsetlowcomplianceKnob.ValueChangedFcn = createCallbackFcn(app, @fascicle_vOffsetlowcomplianceKnobValueChanged, true);
            app.fascicle_aOffsetlowcomplianceKnobLabel = uilabel(app.UIFigure);
            app.fascicle_aOffsetlowcomplianceKnobLabel.Text = {'fascicle_aOffset'; 'low compliance'; ''};
            app.fascicle_aOffsetlowcomplianceKnob = uiknob(app.UIFigure, 'continuous');
            app.fascicle_aOffsetlowcomplianceKnob.ValueChangedFcn = createCallbackFcn(app, @fascicle_aOffsetlowcomplianceKnobValueChanged, true);
            app.fascicle_lOffsethighcomplianceKnob_2Label = uilabel(app.UIFigure);
            app.fascicle_lOffsethighcomplianceKnob_2Label.Text = {'fascicle_lOffset'; 'high compliance'; ''};
            app.fascicle_lOffsethighcomplianceKnob_2 = uiknob(app.UIFigure, 'continuous');
            app.fascicle_lOffsethighcomplianceKnob_2.Limits = [0 500];
            app.fascicle_lOffsethighcomplianceKnob_2.ValueChangedFcn = createCallbackFcn(app, @fascicle_lOffsethighcomplianceKnob_2ValueChanged, true);
            app.fascicle_vOffsethighcomplianceKnobLabel = uilabel(app.UIFigure);
            app.fascicle_vOffsethighcomplianceKnobLabel.Text = {'fascicle_vOffset'; 'high compliance'; ''};
            app.fascicle_vOffsethighcomplianceKnob = uiknob(app.UIFigure, 'continuous');
            app.fascicle_vOffsethighcomplianceKnob.Limits = [0 500];
            app.fascicle_vOffsethighcomplianceKnob.ValueChangedFcn = createCallbackFcn(app, @fascicle_vOffsethighcomplianceKnobValueChanged, true);
            app.fascicle_aOffsethighcomplianceKnobLabel = uilabel(app.UIFigure);
            app.fascicle_aOffsethighcomplianceKnobLabel.Text = {'fascicle_aOffset'; 'high compliance'; ''};
            app.fascicle_aOffsethighcomplianceKnob = uiknob(app.UIFigure, 'continuous');
            app.fascicle_aOffsethighcomplianceKnob.ValueChangedFcn = createCallbackFcn(app, @fascicle_aOffsethighcomplianceKnobValueChanged, true);
            app.FRplot = uiaxes(app.UIFigure);
            xlabel(app.FRplot, 'Sample time')
            ylabel(app.FRplot, 'Firing rate')
            app.Fplot = uiaxes(app.UIFigure);
            xlabel(app.Fplot, 'Sample time')
            ylabel(app.Fplot, 'Yank')
            app.dFplot = uiaxes(app.UIFigure);
            xlabel(app.dFplot, 'Sample time')
            ylabel(app.dFplot, 'Velocity')
            app.lplot = uiaxes(app.UIFigure);
            xlabel(app.lplot, 'Sample time')
            ylabel(app.lplot, 'Force')
            app.vplot = uiaxes(app.UIFigure);
            xlabel(app.vplot, 'Sample time')
            ylabel(app.vplot, 'Length')
            app.aplot = uiaxes(app.UIFigure);
            xlabel(app.aplot, 'Sample time')
            ylabel(app.aplot, 'Acceleration')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_SNS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end